classdef design_page_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HarryHappyInLoveSongPanel       matlab.ui.container.Panel
        LiamPayneSongRecommendationPanel  matlab.ui.container.Panel
        SubmitButton_5                  matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_5  matlab.ui.container.ButtonGroup
        itscomplicatedButton_5          matlab.ui.control.RadioButton
        singleButton_5                  matlab.ui.control.RadioButton
        heartbrokenButton_5             matlab.ui.control.RadioButton
        inloveButton_5                  matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_5   matlab.ui.container.ButtonGroup
        SadButton_5                     matlab.ui.control.RadioButton
        HappyButton_5                   matlab.ui.control.RadioButton
        NiallHoranSongRecommendationPanel  matlab.ui.container.Panel
        SubmitButton_4                  matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_4  matlab.ui.container.ButtonGroup
        itscomplicatedButton_4          matlab.ui.control.RadioButton
        singleButton_4                  matlab.ui.control.RadioButton
        heartbrokenButton_4             matlab.ui.control.RadioButton
        inloveButton_4                  matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_4   matlab.ui.container.ButtonGroup
        SadButton_4                     matlab.ui.control.RadioButton
        HappyButton_4                   matlab.ui.control.RadioButton
        ZaynMalikSongRecommendationPanel  matlab.ui.container.Panel
        SubmitButton_3                  matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_3  matlab.ui.container.ButtonGroup
        itscomplicatedButton_3          matlab.ui.control.RadioButton
        singleButton_3                  matlab.ui.control.RadioButton
        heartbrokenButton_3             matlab.ui.control.RadioButton
        inloveButton_3                  matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_3   matlab.ui.container.ButtonGroup
        SadButton_3                     matlab.ui.control.RadioButton
        HappyButton_3                   matlab.ui.control.RadioButton
        LouisTomlinsonSongRecommendationPanel  matlab.ui.container.Panel
        SubmitButton_2                  matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup_2  matlab.ui.container.ButtonGroup
        itscomplicatedButton_2          matlab.ui.control.RadioButton
        singleButton_2                  matlab.ui.control.RadioButton
        heartbrokenButton_2             matlab.ui.control.RadioButton
        inloveButton_2                  matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup_2   matlab.ui.container.ButtonGroup
        SadButton_2                     matlab.ui.control.RadioButton
        HappyButton_2                   matlab.ui.control.RadioButton
        HarryStylesSongRecommendationPanel  matlab.ui.container.Panel
        SubmitButton                    matlab.ui.control.Button
        WhatsyourrelationshipstatusButtonGroup  matlab.ui.container.ButtonGroup
        itscomplicatedButton            matlab.ui.control.RadioButton
        singleButton                    matlab.ui.control.RadioButton
        heartbrokenButton               matlab.ui.control.RadioButton
        inloveButton                    matlab.ui.control.RadioButton
        HowareyoufeelingButtonGroup     matlab.ui.container.ButtonGroup
        SadButton                       matlab.ui.control.RadioButton
        HappyButton                     matlab.ui.control.RadioButton
        WhichOneDirectionMemberAreYouPanel  matlab.ui.container.Panel
        continueButton_5                matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_4  matlab.ui.container.Panel
        continueButton                  matlab.ui.control.Button
        TextArea_4                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_6  matlab.ui.container.Panel
        continueButton_6                matlab.ui.control.Button
        TextArea_6                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_5  matlab.ui.container.Panel
        continueButton_3                matlab.ui.control.Button
        TextArea_5                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_3  matlab.ui.container.Panel
        continueButton_2                matlab.ui.control.Button
        TextArea_3                      matlab.ui.control.TextArea
        WhichOneDirectionMemberAreYouPanel_2  matlab.ui.container.Panel
        continueButton_4                matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        Image5                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        WhichOneDirectionMemberAreYouLabel  matlab.ui.control.Label
        SUBMITButton                    matlab.ui.control.Button
        Q1WhatisyourfavoritefoodButtonGroup  matlab.ui.container.ButtonGroup
        MexicanfoodButton               matlab.ui.control.RadioButton
        pastaButton                     matlab.ui.control.RadioButton
        WhoneedsfoodwhenyouhavebeerButton  matlab.ui.control.RadioButton
        fastfoodButton                  matlab.ui.control.RadioButton
        fruitsandveggiesButton          matlab.ui.control.RadioButton
        Q2WhatisyourfavoriteseasonButtonGroup  matlab.ui.container.ButtonGroup
        IlikethemallButton              matlab.ui.control.RadioButton
        fallButton                      matlab.ui.control.RadioButton
        springButton                    matlab.ui.control.RadioButton
        winterButton                    matlab.ui.control.RadioButton
        summerButton                    matlab.ui.control.RadioButton
        Q3WhatwouldyouridealfirstdatebeButtonGroup  matlab.ui.container.ButtonGroup
        goingtoacarnivalButton          matlab.ui.control.RadioButton
        fancydinnerdateButton           matlab.ui.control.RadioButton
        stayinginandplayinggamesButton  matlab.ui.control.RadioButton
        goingforadriveButton            matlab.ui.control.RadioButton
        iceskatingButton                matlab.ui.control.RadioButton
        Q8WhichpetwouldyouratherhaveButtonGroup_3  matlab.ui.container.ButtonGroup
        APMAEconwithanentrepreneurshipcertificateButton  matlab.ui.control.RadioButton
        VISAButton                      matlab.ui.control.RadioButton
        HistoryButton                   matlab.ui.control.RadioButton
        MCMButton                       matlab.ui.control.RadioButton
        LiteraryartsButton              matlab.ui.control.RadioButton
        Q8WhichpetwouldyouratherhaveButtonGroup  matlab.ui.container.ButtonGroup
        BeaglepuppyButton               matlab.ui.control.RadioButton
        BlackkittenButton               matlab.ui.control.RadioButton
        GoldenretrieverpuppyButton      matlab.ui.control.RadioButton
        AustralianshepherdpuppyButton   matlab.ui.control.RadioButton
        CalicokittenButton              matlab.ui.control.RadioButton
        Q7WhatisyourfavoritecolorButtonGroup  matlab.ui.container.ButtonGroup
        RedButton                       matlab.ui.control.RadioButton
        YellowButton                    matlab.ui.control.RadioButton
        OrangeButton                    matlab.ui.control.RadioButton
        BlueButton                      matlab.ui.control.RadioButton
        GreenButton                     matlab.ui.control.RadioButton
        Q6WhichroledoyouhaveinyourfriendgroupButtonGroup  matlab.ui.container.ButtonGroup
        DadfriendButton                 matlab.ui.control.RadioButton
        TheonepeoplegotoforadviceButton  matlab.ui.control.RadioButton
        CrazyuncleButton                matlab.ui.control.RadioButton
        ClassclownButton                matlab.ui.control.RadioButton
        MomfriendButton                 matlab.ui.control.RadioButton
        Q5WhichwouldyouratherdoinyourfreetimeButtonGroup  matlab.ui.container.ButtonGroup
        GooutButton                     matlab.ui.control.RadioButton
        DraworpaintButton               matlab.ui.control.RadioButton
        GolfButton                      matlab.ui.control.RadioButton
        PlaysoccerButton                matlab.ui.control.RadioButton
        PilatesmeditationButton         matlab.ui.control.RadioButton
        Q4WhoisyourfavoriteoftheseartistsButtonGroup  matlab.ui.container.ButtonGroup
        ABoogieButton                   matlab.ui.control.RadioButton
        SiaButton                       matlab.ui.control.RadioButton
        LewisCapaldiButton              matlab.ui.control.RadioButton
        SteveAokiButton                 matlab.ui.control.RadioButton
        LizzoButton                     matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SUBMITButton
        function SUBMITButtonPushed(app, event)
            
score_harry = 0; %% INITIALIZE SCORES AND SET ALL TO 0
score_louis = 0;
score_niall = 0;
score_zayn = 0;
score_liam = 0;
app.WhichOneDirectionMemberAreYouPanel.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_2.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_3.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_4.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_5.Visible = 'off';
app.WhichOneDirectionMemberAreYouPanel_6.Visible = 'off';
app.HarryStylesSongRecommendationPanel.Visible='off';
app.HarryHappyInLoveSongPanel.Visible='off';

%%QUESTION 1
if app.fruitsandveggiesButton.Value == true
      score_harry = score_harry + 1;
elseif app.fastfoodButton.Value ==true
      score_louis = score_louis + 1;
elseif app.WhoneedsfoodwhenyouhavebeerButton.Value ==true
      score_niall = score_niall + 1;
elseif app.pastaButton.Value ==true
      score_zayn = score_zayn + 1;
else app.MexicanfoodButton.Value ==true;
      score_liam = score_liam + 1;
end

%%QUESTION 2
if app.summerButton.Value ==true
      score_harry = score_harry + 1;
elseif app.winterButton.Value ==true
      score_louis = score_louis + 1;
elseif app.springButton.Value ==true
      score_niall = score_niall + 1;
elseif app.fallButton.Value ==true
      score_zayn = score_zayn + 1;
else app.IlikethemallButton.Value ==true;
      score_liam = score_liam + 4;
end

%%QUESTION 3
if app.iceskatingButton.Value ==true
      score_harry = score_harry + 1;
elseif app.goingforadriveButton.Value ==true
      score_louis = score_louis + 1;
elseif app.stayinginandplayinggamesButton.Value ==true
      score_niall = score_niall + 1;
elseif app.fancydinnerdateButton.Value ==true
      score_zayn = score_zayn + 1;
else app.goingtoacarnivalButton.Value ==true;
      score_liam = score_liam + 1;
end

%%QUESTION 4
if app.LizzoButton.Value == true
     score_harry = score_harry + 1;
elseif app.SteveAokiButton.Value == true
     score_louis = score_louis + 1;
elseif app.LewisCapaldiButton.Value == true
     score_niall = score_niall + 1;
elseif app.SiaButton.Value== true
     score_zayn = score_zayn + 1;
else app.ABoogieButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 5
if app.PilatesmeditationButton.Value == true
     score_harry = score_harry + 1;
elseif app.PlaysoccerButton.Value == true
     score_louis = score_louis + 1;
elseif app.GolfButton.Value == true
     score_niall = score_niall + 1;
elseif app.DraworpaintButton.Value == true
     score_zayn = score_zayn + 1;
else app.GooutButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 6
if app.MomfriendButton.Value == true
     score_harry = score_harry + 1;
elseif app.ClassclownButton.Value == true
     score_louis = score_louis + 1;
elseif app.CrazyuncleButton.Value == true
     score_niall = score_niall + 1;
elseif app.TheonepeoplegotoforadviceButton.Value == true
     score_zayn = score_zayn + 1;
else app.DadfriendButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 7
if app.GreenButton.Value == true
   score_harry = score_harry + 1;
elseif app.BlueButton.Value == true
   score_louis = score_louis + 1;
elseif app.OrangeButton.Value == true
   score_niall = score_niall + 1;
elseif app.YellowButton.Value == true
   score_zayn = score_zayn + 1;
else app.RedButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 8
if app.CalicokittenButton.Value == true
   score_harry = score_harry + 1;
elseif app.AustralianshepherdpuppyButton.Value == true
   score_louis = score_louis + 1;
elseif app.GoldenretrieverpuppyButton.Value == true
   score_niall = score_niall + 1;
elseif app.BlackkittenButton.Value == true
   score_zayn = score_zayn + 1;
else app.BeaglepuppyButton.Value == true;
   score_liam = score_liam + 1;
end

%%QUESTION 9
if app.LiteraryartsButton.Value == true
   score_harry = score_harry + 1;
elseif app.MCMButton.Value == true
   score_louis = score_louis + 1;
elseif app.HistoryButton.Value == true
   score_niall = score_niall + 1;
elseif app.VISAButton.Value == true
   score_zayn = score_zayn + 1;
else app.APMAEconwithanentrepreneurshipcertificateButton.Value == true;
   score_liam = score_liam + 1;
end

%%RESULTS SECTION
if score_harry > score_louis & score_harry > score_niall & score_harry > score_zayn & score_harry > score_liam;
   disp('Harry Styles!');
   app.WhichOneDirectionMemberAreYouPanel.Visible = 'on';
elseif score_louis > score_harry & score_louis > score_niall & score_louis > score_zayn & score_louis > score_liam;
   disp('Louis Tomlinson!');
   app.WhichOneDirectionMemberAreYouPanel_3.Visible = 'on';
elseif score_niall > score_harry & score_niall > score_louis & score_niall > score_zayn & score_niall > score_liam;
   disp('Niall Horan!');
   app.WhichOneDirectionMemberAreYouPanel_5.Visible = 'on';
elseif score_zayn > score_harry & score_zayn > score_niall & score_zayn > score_liam & score_zayn > score_louis;
   disp('Zayn Malik!');
   app.WhichOneDirectionMemberAreYouPanel_2.Visible = 'on';
elseif score_liam > score_harry & score_liam > score_niall & score_liam > score_zayn & score_liam > score_louis;
   disp('Liam Payne!');
   app.WhichOneDirectionMemberAreYouPanel_4.Visible = 'on';
else score_harry==score_louis | score_harry==score_niall|score_harry==score_zayn|score_harry==score_liam|score_louis==score_niall|score_louis==score_zayn|score_louis==score_liam|score_niall==score_zayn|score_niall==score_liam|score_zayn==score_liam;
    disp('Taylor Swift')
    app.WhichOneDirectionMemberAreYouPanel_6.Visible = 'on';
end

        end

        % Button pushed function: continueButton_5
        function continueButton_5Pushed(app, event)
            app.WhichOneDirectionMemberAreYouPanel.Visible='off';
            app.HarryStylesSongRecommendationPanel.Visible='on';

        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
           app.HarryStylesSongRecommendationPanel.Visible='off';
            if app.HappyButton.Value==true & app.inloveButton.Value==true
                app.HarryHappyInLoveSongPanel.Visible='on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Q4WhoisyourfavoriteoftheseartistsButtonGroup = uibuttongroup(app.UIFigure);
            app.Q4WhoisyourfavoriteoftheseartistsButtonGroup.Title = 'Q4 Who is your favorite of these artists?';
            app.LizzoButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.LizzoButton.Text = 'Lizzo';
            app.LizzoButton.Value = true;
            app.SteveAokiButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.SteveAokiButton.Text = 'Steve Aoki';
            app.LewisCapaldiButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.LewisCapaldiButton.Text = 'Lewis Capaldi';
            app.SiaButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.SiaButton.Text = 'Sia';
            app.ABoogieButton = uiradiobutton(app.Q4WhoisyourfavoriteoftheseartistsButtonGroup);
            app.ABoogieButton.Text = 'A Boogie';
            app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup = uibuttongroup(app.UIFigure);
            app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup.Title = 'Q5 Which would you rather do in your free time?';
            app.PilatesmeditationButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.PilatesmeditationButton.Text = 'Pilates & meditation';
            app.PilatesmeditationButton.Value = true;
            app.PlaysoccerButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.PlaysoccerButton.Text = 'Play soccer';
            app.GolfButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.GolfButton.Text = 'Golf';
            app.DraworpaintButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.DraworpaintButton.Text = 'Draw or paint';
            app.GooutButton = uiradiobutton(app.Q5WhichwouldyouratherdoinyourfreetimeButtonGroup);
            app.GooutButton.Text = 'Go out';
            app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup = uibuttongroup(app.UIFigure);
            app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup.Title = 'Q6 Which role do you have in your friend group?';
            app.MomfriendButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.MomfriendButton.Text = 'Mom friend';
            app.MomfriendButton.Value = true;
            app.ClassclownButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.ClassclownButton.Text = 'Class clown';
            app.CrazyuncleButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.CrazyuncleButton.Text = 'Crazy uncle';
            app.TheonepeoplegotoforadviceButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.TheonepeoplegotoforadviceButton.Text = 'The one people go to for advice';
            app.DadfriendButton = uiradiobutton(app.Q6WhichroledoyouhaveinyourfriendgroupButtonGroup);
            app.DadfriendButton.Text = 'Dad friend';
            app.Q7WhatisyourfavoritecolorButtonGroup = uibuttongroup(app.UIFigure);
            app.Q7WhatisyourfavoritecolorButtonGroup.Title = 'Q7 What is your favorite color?';
            app.GreenButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.GreenButton.Text = 'Green';
            app.GreenButton.Value = true;
            app.BlueButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.OrangeButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.OrangeButton.Text = 'Orange';
            app.YellowButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.YellowButton.Text = 'Yellow';
            app.RedButton = uiradiobutton(app.Q7WhatisyourfavoritecolorButtonGroup);
            app.RedButton.Text = 'Red';
            app.Q8WhichpetwouldyouratherhaveButtonGroup = uibuttongroup(app.UIFigure);
            app.Q8WhichpetwouldyouratherhaveButtonGroup.Title = 'Q8 Which pet would you rather have?';
            app.CalicokittenButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.CalicokittenButton.Text = {'Calico kitten'; ''};
            app.CalicokittenButton.Value = true;
            app.AustralianshepherdpuppyButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.AustralianshepherdpuppyButton.Text = 'Australian shepherd puppy ';
            app.GoldenretrieverpuppyButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.GoldenretrieverpuppyButton.Text = 'Golden retriever puppy';
            app.BlackkittenButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.BlackkittenButton.Text = 'Black kitten';
            app.BeaglepuppyButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup);
            app.BeaglepuppyButton.Text = 'Beagle puppy ';
            app.Q8WhichpetwouldyouratherhaveButtonGroup_3 = uibuttongroup(app.UIFigure);
            app.Q8WhichpetwouldyouratherhaveButtonGroup_3.Title = 'Q9 If you weren’t studying CLPS, what else would you want to study at Brown?';
            app.LiteraryartsButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.LiteraryartsButton.Text = 'Literary arts ';
            app.LiteraryartsButton.Value = true;
            app.MCMButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.MCMButton.Text = 'MCM';
            app.HistoryButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.HistoryButton.Text = 'History';
            app.VISAButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.VISAButton.Text = 'VISA';
            app.APMAEconwithanentrepreneurshipcertificateButton = uiradiobutton(app.Q8WhichpetwouldyouratherhaveButtonGroup_3);
            app.APMAEconwithanentrepreneurshipcertificateButton.Text = 'APMA Econ with an entrepreneurship certificate ';
            app.Q3WhatwouldyouridealfirstdatebeButtonGroup = uibuttongroup(app.UIFigure);
            app.Q3WhatwouldyouridealfirstdatebeButtonGroup.Title = 'Q3 What would your ideal first date be?';
            app.iceskatingButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.iceskatingButton.Text = 'ice skating';
            app.iceskatingButton.Value = true;
            app.goingforadriveButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.goingforadriveButton.Text = 'going for a drive';
            app.stayinginandplayinggamesButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.stayinginandplayinggamesButton.Text = 'staying in and playing games';
            app.fancydinnerdateButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.fancydinnerdateButton.Text = 'fancy dinner date';
            app.goingtoacarnivalButton = uiradiobutton(app.Q3WhatwouldyouridealfirstdatebeButtonGroup);
            app.goingtoacarnivalButton.Text = 'going to a carnival';
            app.Q2WhatisyourfavoriteseasonButtonGroup = uibuttongroup(app.UIFigure);
            app.Q2WhatisyourfavoriteseasonButtonGroup.Title = 'Q2 What is your favorite season?';
            app.summerButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.summerButton.Text = 'summer';
            app.summerButton.Value = true;
            app.winterButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.winterButton.Text = 'winter';
            app.springButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.springButton.Text = 'spring';
            app.fallButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.fallButton.Text = 'fall';
            app.IlikethemallButton = uiradiobutton(app.Q2WhatisyourfavoriteseasonButtonGroup);
            app.IlikethemallButton.Text = 'I like them all!';
            app.Q1WhatisyourfavoritefoodButtonGroup = uibuttongroup(app.UIFigure);
            app.Q1WhatisyourfavoritefoodButtonGroup.Title = 'Q1 What is your favorite food?';
            app.fruitsandveggiesButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.fruitsandveggiesButton.Text = 'fruits and veggies';
            app.fruitsandveggiesButton.Value = true;
            app.fastfoodButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.fastfoodButton.Text = 'fast food';
            app.WhoneedsfoodwhenyouhavebeerButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.WhoneedsfoodwhenyouhavebeerButton.Text = 'Who needs food when you have beer?';
            app.pastaButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.pastaButton.Text = 'pasta!!';
            app.MexicanfoodButton = uiradiobutton(app.Q1WhatisyourfavoritefoodButtonGroup);
            app.MexicanfoodButton.Text = 'Mexican food';
            app.SUBMITButton = uibutton(app.UIFigure, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.WhichOneDirectionMemberAreYouLabel = uilabel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouLabel.Text = 'Which One Direction Member Are You?';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IMG_8323.JPG');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '73ef9ca68981d5688ce7673ec4001abb.jpg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '68305cf6fa5c7a4b56bac69cd797e812.jpg');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'c45258f08ee609c62cb79d58ac394eba.jpg');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'cb1d109659d69e6bf0d3c9c9aa37934f.jpg');
            app.WhichOneDirectionMemberAreYouPanel_2 = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel_2.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_2.Visible = 'off';
            app.TextArea_2 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_2);
            app.TextArea_2.Value = {''; 'ZAYN MALIK'};
            app.continueButton_4 = uibutton(app.WhichOneDirectionMemberAreYouPanel_2, 'push');
            app.continueButton_4.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_3 = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel_3.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_3.Visible = 'off';
            app.TextArea_3 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_3);
            app.TextArea_3.Value = {'LOUIS TOMLINSON'};
            app.continueButton_2 = uibutton(app.WhichOneDirectionMemberAreYouPanel_3, 'push');
            app.continueButton_2.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_5 = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel_5.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_5.Visible = 'off';
            app.TextArea_5 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_5);
            app.TextArea_5.Value = {''; 'NIALL HORAN'};
            app.continueButton_3 = uibutton(app.WhichOneDirectionMemberAreYouPanel_5, 'push');
            app.continueButton_3.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_6 = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel_6.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_6.Visible = 'off';
            app.TextArea_6 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_6);
            app.TextArea_6.Value = {'omg?!? you''re NOT a 1D member... you''re TAYLOR SWIFT'};
            app.continueButton_6 = uibutton(app.WhichOneDirectionMemberAreYouPanel_6, 'push');
            app.continueButton_6.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel_4 = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel_4.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel_4.Visible = 'off';
            app.TextArea_4 = uitextarea(app.WhichOneDirectionMemberAreYouPanel_4);
            app.TextArea_4.Value = {''; 'LIAM PAYNE'};
            app.continueButton = uibutton(app.WhichOneDirectionMemberAreYouPanel_4, 'push');
            app.continueButton.Text = 'continue';
            app.WhichOneDirectionMemberAreYouPanel = uipanel(app.UIFigure);
            app.WhichOneDirectionMemberAreYouPanel.Title = 'Which One Direction Member Are You?';
            app.WhichOneDirectionMemberAreYouPanel.Visible = 'off';
            app.TextArea = uitextarea(app.WhichOneDirectionMemberAreYouPanel);
            app.TextArea.Value = {'HARRY STYLES'};
            app.continueButton_5 = uibutton(app.WhichOneDirectionMemberAreYouPanel, 'push');
            app.continueButton_5.ButtonPushedFcn = createCallbackFcn(app, @continueButton_5Pushed, true);
            app.continueButton_5.Text = 'continue';
            app.HarryStylesSongRecommendationPanel = uipanel(app.UIFigure);
            app.HarryStylesSongRecommendationPanel.Title = 'Harry Styles Song Recommendation';
            app.HarryStylesSongRecommendationPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup = uibuttongroup(app.HarryStylesSongRecommendationPanel);
            app.HowareyoufeelingButtonGroup.Title = 'How are you feeling?';
            app.HappyButton = uiradiobutton(app.HowareyoufeelingButtonGroup);
            app.HappyButton.Text = 'Happy';
            app.HappyButton.Value = true;
            app.SadButton = uiradiobutton(app.HowareyoufeelingButtonGroup);
            app.SadButton.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup = uibuttongroup(app.HarryStylesSongRecommendationPanel);
            app.WhatsyourrelationshipstatusButtonGroup.Title = 'What''s your relationship status?';
            app.inloveButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup);
            app.inloveButton.Text = 'in love';
            app.inloveButton.Value = true;
            app.heartbrokenButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup);
            app.heartbrokenButton.Text = 'heartbroken';
            app.singleButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup);
            app.singleButton.Text = 'single';
            app.itscomplicatedButton = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup);
            app.itscomplicatedButton.Text = 'it''s complicated';
            app.SubmitButton = uibutton(app.HarryStylesSongRecommendationPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.LouisTomlinsonSongRecommendationPanel = uipanel(app.UIFigure);
            app.LouisTomlinsonSongRecommendationPanel.Title = 'Louis Tomlinson Song Recommendation';
            app.LouisTomlinsonSongRecommendationPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_2 = uibuttongroup(app.LouisTomlinsonSongRecommendationPanel);
            app.HowareyoufeelingButtonGroup_2.Title = 'How are you feeling?';
            app.HappyButton_2 = uiradiobutton(app.HowareyoufeelingButtonGroup_2);
            app.HappyButton_2.Text = 'Happy';
            app.HappyButton_2.Value = true;
            app.SadButton_2 = uiradiobutton(app.HowareyoufeelingButtonGroup_2);
            app.SadButton_2.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_2 = uibuttongroup(app.LouisTomlinsonSongRecommendationPanel);
            app.WhatsyourrelationshipstatusButtonGroup_2.Title = 'What''s your relationship status?';
            app.inloveButton_2 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_2);
            app.inloveButton_2.Text = 'in love';
            app.inloveButton_2.Value = true;
            app.heartbrokenButton_2 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_2);
            app.heartbrokenButton_2.Text = 'heartbroken';
            app.singleButton_2 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_2);
            app.singleButton_2.Text = 'single';
            app.itscomplicatedButton_2 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_2);
            app.itscomplicatedButton_2.Text = 'it''s complicated';
            app.SubmitButton_2 = uibutton(app.LouisTomlinsonSongRecommendationPanel, 'push');
            app.SubmitButton_2.Text = 'Submit';
            app.ZaynMalikSongRecommendationPanel = uipanel(app.UIFigure);
            app.ZaynMalikSongRecommendationPanel.Title = 'Zayn Malik Song Recommendation';
            app.ZaynMalikSongRecommendationPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_3 = uibuttongroup(app.ZaynMalikSongRecommendationPanel);
            app.HowareyoufeelingButtonGroup_3.Title = 'How are you feeling?';
            app.HappyButton_3 = uiradiobutton(app.HowareyoufeelingButtonGroup_3);
            app.HappyButton_3.Text = 'Happy';
            app.HappyButton_3.Value = true;
            app.SadButton_3 = uiradiobutton(app.HowareyoufeelingButtonGroup_3);
            app.SadButton_3.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_3 = uibuttongroup(app.ZaynMalikSongRecommendationPanel);
            app.WhatsyourrelationshipstatusButtonGroup_3.Title = 'What''s your relationship status?';
            app.inloveButton_3 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_3);
            app.inloveButton_3.Text = 'in love';
            app.inloveButton_3.Value = true;
            app.heartbrokenButton_3 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_3);
            app.heartbrokenButton_3.Text = 'heartbroken';
            app.singleButton_3 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_3);
            app.singleButton_3.Text = 'single';
            app.itscomplicatedButton_3 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_3);
            app.itscomplicatedButton_3.Text = 'it''s complicated';
            app.SubmitButton_3 = uibutton(app.ZaynMalikSongRecommendationPanel, 'push');
            app.SubmitButton_3.Text = 'Submit';
            app.NiallHoranSongRecommendationPanel = uipanel(app.UIFigure);
            app.NiallHoranSongRecommendationPanel.Title = 'Niall Horan Song Recommendation';
            app.NiallHoranSongRecommendationPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_4 = uibuttongroup(app.NiallHoranSongRecommendationPanel);
            app.HowareyoufeelingButtonGroup_4.Title = 'How are you feeling?';
            app.HappyButton_4 = uiradiobutton(app.HowareyoufeelingButtonGroup_4);
            app.HappyButton_4.Text = 'Happy';
            app.HappyButton_4.Value = true;
            app.SadButton_4 = uiradiobutton(app.HowareyoufeelingButtonGroup_4);
            app.SadButton_4.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_4 = uibuttongroup(app.NiallHoranSongRecommendationPanel);
            app.WhatsyourrelationshipstatusButtonGroup_4.Title = 'What''s your relationship status?';
            app.inloveButton_4 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_4);
            app.inloveButton_4.Text = 'in love';
            app.inloveButton_4.Value = true;
            app.heartbrokenButton_4 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_4);
            app.heartbrokenButton_4.Text = 'heartbroken';
            app.singleButton_4 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_4);
            app.singleButton_4.Text = 'single';
            app.itscomplicatedButton_4 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_4);
            app.itscomplicatedButton_4.Text = 'it''s complicated';
            app.SubmitButton_4 = uibutton(app.NiallHoranSongRecommendationPanel, 'push');
            app.SubmitButton_4.Text = 'Submit';
            app.LiamPayneSongRecommendationPanel = uipanel(app.UIFigure);
            app.LiamPayneSongRecommendationPanel.Title = 'Liam Payne Song Recommendation';
            app.LiamPayneSongRecommendationPanel.Visible = 'off';
            app.HowareyoufeelingButtonGroup_5 = uibuttongroup(app.LiamPayneSongRecommendationPanel);
            app.HowareyoufeelingButtonGroup_5.Title = 'How are you feeling?';
            app.HappyButton_5 = uiradiobutton(app.HowareyoufeelingButtonGroup_5);
            app.HappyButton_5.Text = 'Happy';
            app.HappyButton_5.Value = true;
            app.SadButton_5 = uiradiobutton(app.HowareyoufeelingButtonGroup_5);
            app.SadButton_5.Text = 'Sad';
            app.WhatsyourrelationshipstatusButtonGroup_5 = uibuttongroup(app.LiamPayneSongRecommendationPanel);
            app.WhatsyourrelationshipstatusButtonGroup_5.Title = 'What''s your relationship status?';
            app.inloveButton_5 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_5);
            app.inloveButton_5.Text = 'in love';
            app.inloveButton_5.Value = true;
            app.heartbrokenButton_5 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_5);
            app.heartbrokenButton_5.Text = 'heartbroken';
            app.singleButton_5 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_5);
            app.singleButton_5.Text = 'single';
            app.itscomplicatedButton_5 = uiradiobutton(app.WhatsyourrelationshipstatusButtonGroup_5);
            app.itscomplicatedButton_5.Text = 'it''s complicated';
            app.SubmitButton_5 = uibutton(app.LiamPayneSongRecommendationPanel, 'push');
            app.SubmitButton_5.Text = 'Submit';
            app.HarryHappyInLoveSongPanel = uipanel(app.UIFigure);
            app.HarryHappyInLoveSongPanel.Title = 'Harry Happy In Love Song';
            app.HarryHappyInLoveSongPanel.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = design_page_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end